classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        mangoconditionsLabel          matlab.ui.control.Label
        humidity_inSpinnerLabel       matlab.ui.control.Label
        humidity_inSpinner            matlab.ui.control.Spinner
        humidity_outSpinnerLabel      matlab.ui.control.Label
        humidity_outSpinner           matlab.ui.control.Spinner
        ResetButton                   matlab.ui.control.Button
        airconditionsLabel            matlab.ui.control.Label
        temperature_airCSpinnerLabel  matlab.ui.control.Label
        temperature_airCSpinner       matlab.ui.control.Spinner
        humidity_airSpinnerLabel      matlab.ui.control.Label
        humidity_airSpinner           matlab.ui.control.Spinner
        ResetButton_2                 matlab.ui.control.Button
        ovensettingsLabel             matlab.ui.control.Label
        oven_typeDropDownLabel        matlab.ui.control.Label
        oven_typeDropDown             matlab.ui.control.DropDown
        airflowm3sSpinnerLabel        matlab.ui.control.Label
        airflowm3sSpinner             matlab.ui.control.Spinner
        temperature_inCSpinnerLabel   matlab.ui.control.Label
        temperature_inCSpinner        matlab.ui.control.Spinner
        temperature_outCSpinnerLabel  matlab.ui.control.Label
        temperature_outCSpinner       matlab.ui.control.Spinner
        ResetButton_3                 matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        Q_per_kgkJLabel               matlab.ui.control.Label
        Label_Qdot                    matlab.ui.control.Label
        air_per_kgm3Label             matlab.ui.control.Label
        Label_AirFlow                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        def % variable to store the default values
    end
    
    methods (Access = private)
        function draw_psych_base(app)
            fig = openfig('storage/psychplot_base.fig', 'invisible');
            ax = findobj(fig,'Type','Axes');
            copyobj(ax.Children, app.UIAxes);
            delete(fig);
            app.UIAxes.YLim = [0, 35];
            app.UIAxes.YAxisLocation = 'right';
            app.UIAxes.YLabel.String = 'specific humidity [g/kg]';
            app.UIAxes.XLabel.String = 'temperature [°C]';
        end
        
        function [energy_per_kg_mango, air_per_kg_mango] = draw_psych(app)
            f = waitbar(0, 'starting calculations');
            
            hold(app.UIAxes, "off");
            
            waitbar(0.3, f, 'calculate specific data');
            if app.oven_typeDropDown.Value == "ideal"
                [energy_per_kg_mango, air_per_kg_mango] = ...
                idealDrying(app.UIAxes, app.humidity_inSpinner.Value, app.humidity_outSpinner.Value, ...
                app.temperature_airCSpinner.Value, app.humidity_airSpinner.Value, ...
                app.temperature_inCSpinner.Value, app.temperature_outCSpinner.Value);
            end
            
            waitbar(0.6, f, 'output of specific data')
            app.Label_Qdot.Text = num2str(round(energy_per_kg_mango/1000));
            app.Label_AirFlow.Text = num2str(round(air_per_kg_mango));
            
            waitbar(0.8, f, 'draw psychrometric chart')
            hold(app.UIAxes, "on");
            draw_psych_base(app);
            
            waitbar(1, f, 'finishing')
            close(f);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.def = load('defaults.mat');
            
            draw_psych_base(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.humidity_inSpinner.Value  = app.def.man_w_c_in*100;
            app.humidity_outSpinner.Value = app.def.man_w_c_out*100;
            draw_psych(app);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.temperature_airCSpinner.Value = app.def.s_air_t_cels;
            app.humidity_airSpinner.Value = app.def.s_air_humidity*100;
            draw_psych(app);
        end

        % Value changed function: humidity_inSpinner
        function humidity_inSpinnerValueChanged(app, event)
            draw_psych(app);
        end

        % Value changed function: humidity_outSpinner
        function humidity_outSpinnerValueChanged(app, event)
            draw_psych(app);
        end

        % Value changed function: temperature_airCSpinner
        function temperature_airCSpinnerValueChanged(app, event)
            draw_psych(app);
        end

        % Value changed function: humidity_airSpinner
        function humidity_airSpinnerValueChanged(app, event)
            draw_psych(app);
        end

        % Value changed function: airflowm3sSpinner
        function airflowm3sSpinnerValueChanged(app, event)
            draw_psych(app);
        end

        % Value changed function: temperature_inCSpinner
        function temperature_inCSpinnerValueChanged(app, event)
            draw_psych(app);
        end

        % Value changed function: temperature_outCSpinner
        function temperature_outCSpinnerValueChanged(app, event)
            draw_psych(app);
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.temperature_inCSpinner.Value = app.def.air_in_t_cels;
            app.temperature_outCSpinner.Value = app.def.air_out_t_cels;
            draw_psych(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.mangoconditionsLabel = uilabel(app.GridLayout2);
            app.mangoconditionsLabel.Text = 'mango-conditions';
            app.humidity_inSpinnerLabel = uilabel(app.GridLayout2);
            app.humidity_inSpinnerLabel.Text = 'humidity_in [%]';
            app.humidity_inSpinner = uispinner(app.GridLayout2);
            app.humidity_inSpinner.Limits = [0 100];
            app.humidity_inSpinner.ValueChangedFcn = createCallbackFcn(app, @humidity_inSpinnerValueChanged, true);
            app.humidity_outSpinnerLabel = uilabel(app.GridLayout2);
            app.humidity_outSpinnerLabel.Text = 'humidity_out [%]';
            app.humidity_outSpinner = uispinner(app.GridLayout2);
            app.humidity_outSpinner.Limits = [0 100];
            app.humidity_outSpinner.ValueChangedFcn = createCallbackFcn(app, @humidity_outSpinnerValueChanged, true);
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.airconditionsLabel = uilabel(app.GridLayout2);
            app.airconditionsLabel.Text = 'air-conditions';
            app.temperature_airCSpinnerLabel = uilabel(app.GridLayout2);
            app.temperature_airCSpinnerLabel.Text = 'temperature_air [°C]';
            app.temperature_airCSpinner = uispinner(app.GridLayout2);
            app.temperature_airCSpinner.Limits = [0 60];
            app.temperature_airCSpinner.ValueChangedFcn = createCallbackFcn(app, @temperature_airCSpinnerValueChanged, true);
            app.humidity_airSpinnerLabel = uilabel(app.GridLayout2);
            app.humidity_airSpinnerLabel.Text = 'humidity_air [%]';
            app.humidity_airSpinner = uispinner(app.GridLayout2);
            app.humidity_airSpinner.Limits = [0 100];
            app.humidity_airSpinner.ValueChangedFcn = createCallbackFcn(app, @humidity_airSpinnerValueChanged, true);
            app.ResetButton_2 = uibutton(app.GridLayout2, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.ovensettingsLabel = uilabel(app.GridLayout2);
            app.ovensettingsLabel.Text = 'oven-settings';
            app.oven_typeDropDownLabel = uilabel(app.GridLayout2);
            app.oven_typeDropDownLabel.Text = 'oven_type';
            app.oven_typeDropDown = uidropdown(app.GridLayout2);
            app.oven_typeDropDown.Items = {'ideal', 'heat-pump', 'ATESTA', 'container dryer'};
            app.oven_typeDropDown.Value = 'ideal';
            app.airflowm3sSpinnerLabel = uilabel(app.GridLayout2);
            app.airflowm3sSpinnerLabel.Text = 'air-flow [m3/s]';
            app.airflowm3sSpinner = uispinner(app.GridLayout2);
            app.airflowm3sSpinner.Limits = [0 1000];
            app.airflowm3sSpinner.ValueChangedFcn = createCallbackFcn(app, @airflowm3sSpinnerValueChanged, true);
            app.temperature_inCSpinnerLabel = uilabel(app.GridLayout2);
            app.temperature_inCSpinnerLabel.Text = 'temperature_in [°C]';
            app.temperature_inCSpinner = uispinner(app.GridLayout2);
            app.temperature_inCSpinner.Limits = [0 90];
            app.temperature_inCSpinner.ValueChangedFcn = createCallbackFcn(app, @temperature_inCSpinnerValueChanged, true);
            app.temperature_outCSpinnerLabel = uilabel(app.GridLayout2);
            app.temperature_outCSpinnerLabel.Text = 'temperature_out [°C]';
            app.temperature_outCSpinner = uispinner(app.GridLayout2);
            app.temperature_outCSpinner.Limits = [0 90];
            app.temperature_outCSpinner.ValueChangedFcn = createCallbackFcn(app, @temperature_outCSpinnerValueChanged, true);
            app.ResetButton_3 = uibutton(app.GridLayout2, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'psychrometric chart of drying process')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.Q_per_kgkJLabel = uilabel(app.GridLayout4);
            app.Q_per_kgkJLabel.Text = 'Q_per_kg [kJ]';
            app.Label_Qdot = uilabel(app.GridLayout4);
            app.Label_Qdot.Text = '';
            app.air_per_kgm3Label = uilabel(app.GridLayout4);
            app.air_per_kgm3Label.Text = 'air_per_kg [m3]';
            app.Label_AirFlow = uilabel(app.GridLayout4);
            app.Label_AirFlow.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end